classdef limo_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        text4         matlab.ui.control.Label
        other_panel   matlab.ui.container.Panel
        results       matlab.ui.control.Button
        contrasts     matlab.ui.control.Button
        limo_tools    matlab.ui.control.Button
        Title         matlab.ui.control.Label
        uipanel6      matlab.ui.container.Panel
        Rdx           matlab.ui.control.Button
        pushbutton20  matlab.ui.control.Button
        analyse       matlab.ui.control.Button
        Quit          matlab.ui.control.Button
        Help          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function limo_gui_OpeningFcn(app, varargin)
            % --------------------------------------------------
            %   Executes just before the menu is made visible
            % --------------------------------------------------
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            handles.dir = pwd;
            guidata(hObject, handles);
        end

        % Button pushed function: Help
        function Help_Callback(app, event)
            % --- Executes on button press in Help.
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            origin = which('limo_eeg'); origin = origin(1:end-10);
            origin = sprintf('%shelp',origin); cd(origin)
            web(['file://' which('limo_gui.html')]);
            cd (handles.dir)
        end

        % Button pushed function: Quit
        function Quit_Callback(app, event)
            % --- Executes on button press in Quit.
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            clc
            uiresume
            try
                parpool('close');
            end
            delete(handles.figure1)
        end

        % Button pushed function: Rdx
        function Rdx_Callback(app, event)
            % --- Executes on button press in Rdx.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            uiresume
            delete(handles.figure1)
            limo_random_effect_App;
        end

        % Button pushed function: analyse
        function analyse_Callback(app, event)
            % --- Executes on button press in analyse.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [file,dir_path] = uigetfile('LIMO.mat','select a LIMO.mat file for re-analysis');
            [Names,Paths,Files] = limo_get_files([],{'*.mat;*.txt'},'Select a LIMO.mat file or a list of such files');
            if isempty(Names)
                return
                guidata(hObject, handles);
            else
                for f=1:size(Files,2)
                    load(Files{f});
                    if LIMO.Level ~=1
                        fprintf('cannot reprocess 2nd level LIMO.mat\n %s\n',Paths{f})
                    else
                        cd(LIMO.dir)
                        LIMO.design.status = 'to do';
                        save('LIMO','LIMO');
                        fprintf('reprocessing \n %s\n',Paths{f})
                        limo_eeg(4);
                        if isfield(LIMO,'contrast')
                            limo_eeg(6);
                        end
                    end
                    uiresume; delete(handles.figure1);
                    cd (dir_path); limo_results_App;
                end
            end
        end

        % Button pushed function: contrasts
        function contrasts_Callback(app, event)
            % --- Executes on button press in contrasts.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            clc; uiresume
            guidata(hObject, handles);
            opt = questdlg('run constrasts for all subjects or open one subject?','option','all','one','all');
            delete(handles.figure1)
            if strcmp(opt,'one')
                limo_contrast_manager
            else          
                limo_batch('contrast only');
            end
        end

        % Button pushed function: pushbutton20
        function import_Callback(app, event)
            % --- Executes on button press in import
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            uiresume
            delete(handles.figure1)
            limo_batch('model specification');
        end

        % Button pushed function: limo_tools
        function limo_tools_Callback(app, event)
            % --- Executes on button press in limo_tools.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            uiresume
            delete(handles.figure1)
            limo_tools_App;
        end

        % Button pushed function: results
        function results_Callback(app, event)
            % --- Executes on button press in results.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            uiresume
            delete(handles.figure1)
            limo_results_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'limo_gui';
            app.figure1.Tag = 'figure1';
            app.Help = uibutton(app.figure1, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Tag = 'Help';
            app.Help.Tooltip = 'Help';
            app.Help.Text = 'Help';
            app.Quit = uibutton(app.figure1, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @Quit_Callback, true);
            app.Quit.Tag = 'Quit';
            app.Quit.Tooltip = 'Quit';
            app.Quit.Text = 'Quit';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Analyses';
            app.uipanel6.Tag = 'uipanel6';
            app.analyse = uibutton(app.uipanel6, 'push');
            app.analyse.ButtonPushedFcn = createCallbackFcn(app, @analyse_Callback, true);
            app.analyse.Tag = 'analyse';
            app.analyse.Tooltip = 'Re-estimate parameters from LIMO.mat ';
            app.analyse.Text = 'Re-analyze';
            app.pushbutton20 = uibutton(app.uipanel6, 'push');
            app.pushbutton20.ButtonPushedFcn = createCallbackFcn(app, @import_Callback, true);
            app.pushbutton20.Tag = 'pushbutton20';
            app.pushbutton20.Tooltip = 'Import data for modelling';
            app.pushbutton20.Text = '1st level: Import & Model';
            app.Rdx = uibutton(app.uipanel6, 'push');
            app.Rdx.ButtonPushedFcn = createCallbackFcn(app, @Rdx_Callback, true);
            app.Rdx.Tag = 'Rdx';
            app.Rdx.Tooltip = 'Load GUI for random effect analyses';
            app.Rdx.Text = '2nd level: Test & Inference';
            app.Title = uilabel(app.figure1);
            app.Title.Tag = 'Title';
            app.Title.Text = 'LINEAR MODELING OF MEEG DATA';
            app.other_panel = uipanel(app.figure1);
            app.other_panel.Title = 'Tools';
            app.other_panel.Tag = 'other_panel';
            app.limo_tools = uibutton(app.other_panel, 'push');
            app.limo_tools.ButtonPushedFcn = createCallbackFcn(app, @limo_tools_Callback, true);
            app.limo_tools.Tag = 'limo_tools';
            app.limo_tools.Tooltip = 'Load GUI for various tools';
            app.limo_tools.Text = 'LIMO tools';
            app.contrasts = uibutton(app.other_panel, 'push');
            app.contrasts.ButtonPushedFcn = createCallbackFcn(app, @contrasts_Callback, true);
            app.contrasts.Tag = 'contrasts';
            app.contrasts.Tooltip = 'Estimate contrasts for all or one subject';
            app.contrasts.Text = 'Contrast manager';
            app.results = uibutton(app.other_panel, 'push');
            app.results.ButtonPushedFcn = createCallbackFcn(app, @results_Callback, true);
            app.results.Tag = 'results';
            app.results.Tooltip = 'Load the GUI to visualize results';
            app.results.Text = 'Results & Plots';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Copyright (C) LIMO Team';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = limo_gui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)limo_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end