classdef limo_random_effect_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        pushbutton18             matlab.ui.control.Button
        pushbutton17             matlab.ui.control.Button
        Plotting                 matlab.ui.control.Button
        text7                    matlab.ui.control.Label
        Title                    matlab.ui.control.Label
        Quit                     matlab.ui.control.Button
        Help                     matlab.ui.control.Button
        Tests_panel              matlab.ui.container.ButtonGroup
        analysis_type            matlab.ui.control.ListBox
        ANOVA                    matlab.ui.control.StateButton
        Regression               matlab.ui.control.StateButton
        Paired_t_test            matlab.ui.control.StateButton
        Two_Samples_t_test       matlab.ui.control.StateButton
        One_Sample_t_test        matlab.ui.control.StateButton
        Basic_stats_panel        matlab.ui.container.ButtonGroup
        togglebutton12           matlab.ui.control.StateButton
        Central_tendency_and_CI  matlab.ui.control.StateButton
        Parameters               matlab.ui.container.Panel
        bootstrap                matlab.ui.control.EditField
        Bootstrap_Checkbox       matlab.ui.control.CheckBox
        TFCE                     matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        function handles = get_chan_loc(app, handles)
            % ----------------------
            % subfunction to find channel locations
            % ----------------------
            
            
            limo_settings_script; % set STUDY and limo_settings
            
            if strcmpi(handles.type,'channels')
                chan_file = fullfile(limo_settings.workdir, 'limo_gp_level_chanlocs.mat');
                if exist(chan_file, 'file')
                    handles.chan_file = chan_file;
                end
                if ~isempty(STUDY) && isfield(STUDY, 'limo') && isfield(STUDY.limo, 'chanloc')
                    if ~isempty(STUDY.limo.chanloc)
                        handles.chan_file = STUDY.limo.chanloc;
                    end
                end
            end
            if ~isempty(handles.chan_file)
                fprintf('using study channel location file \n%s\n',handles.chan_file);
            end
        end
        
        function go_to_working_dir(app)
            % ----------------------
            % go to the right folder
            % ----------------------
            
            if ~exist('limo_settings_script', 'file')
                pathTmp = fileparts(which('limo_eeg'));
                addpath(fullfile(pathTmp, 'external', 'psom'));
            end
            
            limo_settings_script;
            if exist(limo_settings.workdir,'dir')
                cd(limo_settings.workdir);
            end
        end
        
        function go = test_chan_loc(app, handles)
            % -----------------------------------------------------------------------
            % subfunction called before calling the others to test chanlocs is loaded
            % -----------------------------------------------------------------------
            
            
            if isempty(handles.chan_file)
                go = 0;
                limo_warndlg('chanloc not specified, please load one','missing file')
            else
                go = 1;
            end
        end
        
        function go = update_dir(app, handles, test)
            % ----------------------
            % create folder if necessary
            % ----------------------
            
            
            if isempty(handles.dir) && ...
                    strcmpi(fullfile(fileparts(pwd), test),pwd) % already at the right place
                if exist(fullfile(pwd,'LIMO.mat'),'file')
                    res = limo_questdlg(sprintf('The directory "%s" already contains stat files, do you want to overwrite them?',test), ...
                        'Directory containing results', 'Cancel', 'Overwrite', 'Overwrite');
                    if strcmpi(res, 'Cancel')
                        go = 0;
                        return;
                    else
                        handles.dir = pwd; go = 1; %#ok<*NASGU>
                    end
                else
                    handles.dir = pwd; go = 1;
                end
                return
            end
            
            if isempty(handles.dir)
                go_to_working_dir(app); % no effect if limo_settings.workdir is empty
                if exist(fullfile(pwd, test),'dir')
                    count = 2;
                    while exist([ test num2str(count)],'dir')
                        count = count + 1;
                    end
                    newtest = [ test num2str(count)];
                    res = limo_questdlg(sprintf('The directory "%s" already exist, do you want to overwrite it or\ncreate a new one named "%s"?\nIf you overwrite, previous results will be lost.',test,newtest), ...
                        'Directory containing results', 'Cancel', 'Create new', 'Overwrite', 'Overwrite');
                    if strcmpi(res, 'cancel')
                        go = 0;
                        return;
                    elseif strcmpi(res, 'Create new')
                        test = newtest;
                    end
            
                    if exist(test,'dir')
                        disp('Removing folder');
                        rmdir(test, 's');
                    end
                end
                mkdir(test); cd(test); handles.dir = pwd; go = 1;
            
            else
                if exist(fullfile(handles.dir,'LIMO.mat'),'file')
                    res = limo_questdlg(sprintf('The directory "%s" already contains stat files, do you want to overwrite them?',handles.dir), ...
                        'Directory containing results', 'Cancel', 'Overwrite', 'Overwrite');
                    if strcmpi(res, 'cancel')
                        go = 0;
                        return;
                    else
                        go = 1;
                    end
                else
                    go = 1;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function limo_random_effect_OpeningFcn(app, varargin)
            % --------------------------------------------------
            %   Executes just before the menu is made visible
            % --------------------------------------------------
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            guidata(hObject, handles);
            
            % define handles used for the save callback
            handles.b         = 0;
            handles.tfce      = 0;
            handles.type      = 'Channels';
            handles.dir       = [];
            handles.chan_file = [];
            handles         = get_chan_loc(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: analysis_type
        function Analysis_type_Callback(app, event)
            % Analysis type
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            M = get(hObject,'Value');
            if M == 1
                handles.type = 'Channels';
            elseif M == 2
                handles.type = 'Components';
            elseif M == 3
                handles.type = 'Sources';
            end
            guidata(hObject, handles);
        end

        % Value changed function: Bootstrap_Checkbox
        function Bootstrap_Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(hObject, 'value') % tick box on
                handles.b = str2double(get(findobj(hObject.Parent, 'tag', 'bootstrap'),'String'));
                set(findobj(hObject.Parent, 'tag', 'TFCE'),'enable','on');
                set(findobj(hObject.Parent, 'tag', 'bootstrap'),'enable','on');
            else
                handles.b = 0;
                set(findobj(hObject.Parent, 'tag', 'TFCE'),'value',0);
                set(findobj(hObject.Parent, 'tag', 'TFCE'),'enable','off');
                set(findobj(hObject.Parent, 'tag', 'bootstrap'),'enable','off');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton17
        function CD_Callback(app, event)
            % --- Executes on button press in CD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            PathName=uigetdir(pwd,'select LIMO working directory');
            if PathName ~= 0
                list = dir(PathName);
                for i=1:length(list)
                    if strcmp(list(i).name,'LIMO.mat')
                        stop = limo_questdlg('a LIMO file already exists, are you sure you want to move to this directory', ...
                            'WARNING','Yes','No','No');
                        if strcmp(stop,'Yes')
                            cd(PathName);
                            handles.dir = PathName;
                        else
                            return
                        end
                    else
                        cd(PathName);
                        handles.dir = PathName;
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Button pushed function: Help
        function Help_Callback(app, event)
            % --- Executes on button press in Help.
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            web(['file://' which('limo_random_effect.html')]);
        end

        % Button pushed function: Plotting
        function Plotting_Callback(app, event)
            % --- Executes on button press plot
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            uiresume
            guidata(hObject, handles);
            [FileName,PathName,FilterIndex]=limo_get_result_file;
            if FilterIndex ~= 0
                tmp          = load([PathName filesep 'LIMO.mat']);
                limo_display_results(1,FileName,PathName,0.05,1,tmp.LIMO);
            end
            
            %delete(handles.figure1)
            limo_results
        end

        % Button pushed function: Quit
        function Quit_Callback(app, event)
            % --- Executes on button press in close
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            uiresume
            guidata(hObject, handles);
            delete(handles.figure1)
            limo_gui
        end

        % Value changed function: TFCE
        function TFCE_Callback(app, event)
            % --- Executes on button press in TFCE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            M = get(hObject,'Value');
            if M == 1
                handles.tfce = 1;
                disp('tfce is on');
            elseif M == 0
                handles.tfce = 0;
                disp('tfce is off');
            end
            guidata(hObject, handles);
        end

        % Value changed function: bootstrap
        function bootstrap_Callback(app, event)
            % get the number of bootstraps
            % ---------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            handles.b = str2double(get(hObject,'String'));
            if isempty(handles.b)
                handles.b = 0;
            elseif handles.b == 0
                disp('bootstrap set to null')
                set(handles.TFCE,'Value',0)
            else
                fprintf('bootstrap changed to %g \n',handles.b)
                if handles.b > 0 && handles.b < 1000
                    if handles.b ~= 101
                        limo_warndlg(['Our simulations suggest that you need at least 1000 bootstraps, your current value ' num2bouble(handles.b) ' may be overwritten'],'Bootstrap issue');
                    else
                        limo_warndlg('Using boostrap 101 - a trick to test bootstrap functionality, but remember it is not valid')
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton18
        function chan_cluster_neighbours_Callback(app, event)
            % --- Executes on button press in chan_cluster_neighbours.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [chan_file,chan_path,sts]=uigetfile('expected_chanlocs.mat','Select channel location file');
            if sts == 1
                test = load([chan_path chan_file]);
                if isfield(test,'expected_chanlocs')
                    test = test.expected_chanlocs;
                end
            
                if isstruct(test) && ~isempty(test(1).labels) && ~isempty(test(1).theta) && ~isempty(test(1).radius) ...
                        && ~isempty(test(1).X) && ~isempty(test(1).Y) && ~isempty(test(1).Z) && ~isempty(test(1).sph_theta) ...
                         && ~isempty(test(1).sph_phi) && ~isempty(test(1).sph_radius)
            
                    handles.chan_file = [chan_path chan_file];
                    disp('channel location loaded');
                    assignin('base', 'gp_level_chanlocs', [chan_path chan_file])
            
                else
                    limo_warndlg('this file is not recognize as a channel location file or informations are missing','file error')
                end
            end
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'limo_random_effect';
            app.figure1.Tag = 'figure1';
            app.Parameters = uipanel(app.figure1);
            app.Parameters.Title = 'Multiple comparisons correction';
            app.Parameters.Tag = 'Parameters';
            app.TFCE = uicheckbox(app.Parameters);
            app.TFCE.ValueChangedFcn = createCallbackFcn(app, @TFCE_Callback, true);
            app.TFCE.Tag = 'TFCE';
            app.TFCE.Enable = 'off';
            app.TFCE.Tooltip = 'computes TFCE along with the bootstrapped data';
            app.TFCE.Text = 'Threshold Free Cluster Enhancement';
            app.Bootstrap_Checkbox = uicheckbox(app.Parameters);
            app.Bootstrap_Checkbox.ValueChangedFcn = createCallbackFcn(app, @Bootstrap_Checkbox_Callback, true);
            app.Bootstrap_Checkbox.Tag = 'Bootstrap_Checkbox';
            app.Bootstrap_Checkbox.Tooltip = 'computes TFCE along with the bootstrapped data';
            app.Bootstrap_Checkbox.Text = 'Bootstrap for Clustering and Maximum statistics';
            app.bootstrap = uieditfield(app.Parameters, 'text');
            app.bootstrap.ValueChangedFcn = createCallbackFcn(app, @bootstrap_Callback, true);
            app.bootstrap.Tag = 'bootstrap';
            app.bootstrap.Enable = 'off';
            app.bootstrap.Tooltip = 'number of bootstraps to use';
            app.bootstrap.Value = '1000';
            app.Basic_stats_panel = uibuttongroup(app.figure1);
            app.Basic_stats_panel.Title = 'Summary Statistics';
            app.Basic_stats_panel.Tag = 'Basic_stats_panel';
            app.Central_tendency_and_CI = uibutton(app.Basic_stats_panel, 'state');
            app.Central_tendency_and_CI.Tag = 'Central_tendency_and_CI';
            app.Central_tendency_and_CI.Tooltip = 'Computes means, trimmed means, median or Harell Davis of mid decile';
            app.Central_tendency_and_CI.Text = 'Central tendency and CI';
            app.togglebutton12 = uibutton(app.Basic_stats_panel, 'state');
            app.togglebutton12.Tag = 'togglebutton12';
            app.togglebutton12.Tooltip = 'compute a difference between 2 central tendency data set';
            app.togglebutton12.Text = 'Compute a difference and it''s CI';
            app.Tests_panel = uibuttongroup(app.figure1);
            app.Tests_panel.Title = 'Inference tests';
            app.Tests_panel.Tag = 'Tests_panel';
            app.One_Sample_t_test = uibutton(app.Tests_panel, 'state');
            app.One_Sample_t_test.Tag = 'One_Sample_t_test';
            app.One_Sample_t_test.Tooltip = 'Tests if the 20% trimmed mean differs from 0';
            app.One_Sample_t_test.Text = 'One Sample t-test';
            app.Two_Samples_t_test = uibutton(app.Tests_panel, 'state');
            app.Two_Samples_t_test.Tag = 'Two_Samples_t_test';
            app.Two_Samples_t_test.Tooltip = 'Compare the 20% trimmed means between 2 groups (Yuen t-test)';
            app.Two_Samples_t_test.Text = 'Two Samples t-test';
            app.Paired_t_test = uibutton(app.Tests_panel, 'state');
            app.Paired_t_test.Tag = 'Paired_t_test';
            app.Paired_t_test.Tooltip = 'Test the difference between the 20% trimmed of two conditions (Yuen t-test)';
            app.Paired_t_test.Text = 'Paired t-test';
            app.Regression = uibutton(app.Tests_panel, 'state');
            app.Regression.Tag = 'Regression';
            app.Regression.Tooltip = 'IRLS regression (if N>50)';
            app.Regression.Text = 'Regression';
            app.ANOVA = uibutton(app.Tests_panel, 'state');
            app.ANOVA.Tag = 'ANOVA';
            app.ANOVA.Tooltip = 'ANOVAS on group trimmed means and condition means';
            app.ANOVA.Text = 'ANOVA/ANCOVA';
            app.analysis_type = uilistbox(app.Tests_panel);
            app.analysis_type.Items = {'Analysis type: Channels', 'Analysis type: Compoments'};
            app.analysis_type.ValueChangedFcn = createCallbackFcn(app, @Analysis_type_Callback, true);
            app.analysis_type.Tag = 'analysis_type';
            app.analysis_type.Value = 'Analysis type: Channels';
            app.Help = uibutton(app.figure1, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Tag = 'Help';
            app.Help.Tooltip = 'Help';
            app.Help.Text = 'Help';
            app.Quit = uibutton(app.figure1, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @Quit_Callback, true);
            app.Quit.Tag = 'Quit';
            app.Quit.Tooltip = 'Quit';
            app.Quit.Text = 'Quit';
            app.Title = uilabel(app.figure1);
            app.Title.Tag = 'Title';
            app.Title.Text = 'LINEAR MODELING TOOLBOX: Random Effects (2nd level analysis)';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Copyright (C) LIMO Team ';
            app.Plotting = uibutton(app.figure1, 'push');
            app.Plotting.ButtonPushedFcn = createCallbackFcn(app, @Plotting_Callback, true);
            app.Plotting.Tag = 'Plotting';
            app.Plotting.Tooltip = 'Quit';
            app.Plotting.Text = 'Results';
            app.pushbutton17 = uibutton(app.figure1, 'push');
            app.pushbutton17.ButtonPushedFcn = createCallbackFcn(app, @CD_Callback, true);
            app.pushbutton17.Tag = 'pushbutton17';
            app.pushbutton17.Tooltip = 'Change working directory';
            app.pushbutton17.Text = 'Working Directory';
            app.pushbutton18 = uibutton(app.figure1, 'push');
            app.pushbutton18.ButtonPushedFcn = createCallbackFcn(app, @chan_cluster_neighbours_Callback, true);
            app.pushbutton18.Tag = 'pushbutton18';
            app.pushbutton18.Tooltip = 'Load a pre-specified file of electrode locations';
            app.pushbutton18.Text = 'Load expected chan / neighbours';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = limo_random_effect_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)limo_random_effect_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end