classdef limo_results_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        text4            matlab.ui.control.Label
        Stat_panel       matlab.ui.container.Panel
        review_design_2  matlab.ui.control.StateButton
        Image_4          matlab.ui.control.Image
        review_design    matlab.ui.control.StateButton
        MCC_Choice       matlab.ui.control.DropDown
        add_tfce         matlab.ui.control.CheckBox
        add_bootstrap    matlab.ui.control.CheckBox
        p_value          matlab.ui.control.EditField
        text_pvalue      matlab.ui.control.Label
        text_MCC         matlab.ui.control.Label
        Title            matlab.ui.control.Label
        Quit             matlab.ui.control.Button
        Help             matlab.ui.control.Button
        New_Analyzes     matlab.ui.container.ButtonGroup
        Partial_coef     matlab.ui.control.Button
        New_Contrast     matlab.ui.control.StateButton
        Visualize        matlab.ui.container.ButtonGroup
        Image_3          matlab.ui.control.Image
        Image_2          matlab.ui.control.Image
        Image            matlab.ui.control.Image
        ERP              matlab.ui.control.StateButton
        Image_results    matlab.ui.control.StateButton
        Topoplot         matlab.ui.control.StateButton
    end

    
    methods (Access = private)
        function Model_Selection_Callback(app, hObject, eventdata, handles)
            % Model Selection (multivariate).
            % ---------------------------------------------------------------
            [FileName,PathName,FilterIndex]=uigetfile('LIMO.mat','Select a LIMO file');
            cd(PathName); handles.LIMO = load('LIMO.mat');
            limo_model_selection(handles.LIMO.LIMO,1);
            guidata(hObject, handles);
        end

        function handles = check_boot_and_tfce(app, handles, currentfile)
            % -----------------------------------------------------------
            % subroutine to check bootstrap and tfce on any button clicks
            % ------------------------------------------------------------
            if handles.bootstrap ~= 0 && handles.MCC ~= 1 || ...
                    handles.tfce == 1 && handles.MCC == 3

                handles.LIMO.design.bootstrap = handles.bootstrap;
                handles.LIMO.design.tfce      = handles.tfce;

                [filepath,filename,ext] = fileparts(currentfile);

                % deal with bootstrap
                if handles.bootstrap ~= 0
                    if ~exist([filepath filesep 'H0' filesep 'H0_' filename ext],'file')
                        if handles.LIMO.Level == 1
                            if strncmp(filename,'con',3) || strncmp(filename,'ess',3)
                                if exist('warndlg2','file')
                                    warndlg2(sprintf('This contrast cannot be bootstrapped now, \nbootstrap the model and recompute the contrast'))
                                else
                                    warndlg(sprintf('This contrast cannot be bootstrapped now, \nbootstrap the model and recompute the contrast'))
                                end
                            else
                                if strcmp(questdlg('Level 1: are you sure to compute all bootstraps for that subject?','bootstrap turned on','Yes','No','No'),'Yes')
                                    LIMO                  = handles.LIMO;
                                    LIMO.design.bootstrap = 800;
                                    if handles.tfce == 1
                                        LIMO.design.tfce  = 1;
                                    end
                                    save(fullfile(LIMO.dir,'LIMO.mat'),'LIMO')
                                    limo_eeg(4);
                                end
                            end
                        else % handles.LIMO.Level == 2
                            if contains(filename,'one_sample')
                                limo_random_robust(1,fullfile(handles.LIMO.dir,'Yr.mat'),...
                                    str2num(filename(max(strfind(filename,'_'))+1:end)),handles.LIMO);
                            elseif contains(filename,'two_samples')
                                limo_random_robust(2,fullfile(handles.LIMO.dir,'Yr1.mat'),...
                                    fullfile(handles.LIMO.dir,'Yr1.mat'), str2num(filename(max(strfind(filename,'_'))+1:end)),handles.LIMO);
                            elseif contains(filename,'paired_samples')
                                limo_random_robust(3,fullfile(handles.LIMO.dir,'Yr1.mat'),...
                                    fullfile(handles.LIMO.dir,'Yr1.mat'), str2num(filename(max(strfind(filename,'_'))+1:end)),handles.LIMO);
                            elseif contains(filename,'Covariate_effect') && contains(handles.LIMO.design.name,'Regression')
                                LIMO = handles.LIMO; LIMO.design.bootstrap = 1000;
                                save(fullfile(LIMO.dir,'LIMO.mat'),'LIMO');
                                handles.LIMO = LIMO; limo_eeg(4,handles.LIMO.dir); clear LIMO
                            elseif contains(filename,'ANOVA') && ~strncmpi(filename,'Rep_ANOVA',9)
                                limo_random_robust(5,fullfile(handles.LIMO.dir,'Yr.mat'),...
                                    handles.LIMO.data.Cat,handles.LIMO.data.Cont,handles.LIMO,'go','yes');
                            elseif contains(filename,'Rep_ANOVA')
                                if strncmp(filename,'con',3)
                                    if exist([filepath filesep 'H0' filesep 'H0_' filesep 'H0_Betas.mat'],'file')
                                        limo_contrast([filepath filesep 'Yr.mat'], ...
                                            [filepath filesep 'H0' filesep 'H0_' filesep 'H0_Betas.mat'], handles.LIMO, 0,3);
                                    else
                                        errordlg2('there is no GLM bootstrap file for this contrast file')
                                    end
                                elseif strncmp(filename,'ess',3)
                                    if exist([filepath filesep 'H0' filesep 'H0_' filesep 'H0_Betas.mat'],'file')
                                        limo_contrast([filepath filesep 'Yr.mat'], ...
                                            [filepath filesep 'H0' filesep 'H0_' filesep 'H0_Betas.mat'], handles.LIMO, 1,3);
                                    else
                                        errordlg2('there is no bootstrap file for this contrast file')
                                    end
                                else
                                    disp('Bootstraping Repeated Measure ANOVA')
                                    limo_random_robust(6,fullfile(filepath,'Yr.mat'),handles.LIMO.data.Cat, ...
                                        handles.LIMO.design.repeated_measure, handles.LIMO, 'go','yes')
                                end
                            end
                        end
                    end
                end

                % deal with tfce
                if handles.tfce == 1
                    if ~exist([filepath filesep 'tfce' filesep 'tfce_' filename ext],'file')
                        limo_tfce_handling(currentfile,'checkfile','yes')
                    end
                end
            end
        end

        function check_weights_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in check_weight.

            limo_check_weight
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function limo_results_OpeningFcn(app, varargin)
            % --------------------------------------------------
            %   Executes just before the menu is made visible
            % --------------------------------------------------
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');           
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            handles.output = hObject;
            guidata(hObject, handles);
            
            % define handles used for the save callback
            handles.p         = 0.05;
            handles.MCC       = 1;
            handles.dir       = pwd;
            handles.bootstrap = 0;
            handles.tfce      = 0;
            handles.filter    = {'*.mat'};           
            % set(handles.add_bootstrap,'Enable','off')
            % set(handles.add_tfce,'Enable','off')            
            guidata(hObject, handles);
        end

        % Button pushed function: Help
        function Help_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            web(fullfile(fileparts(which('limo_eeg')),[filesep 'help' filesep 'limo_results.html']));
            guidata(hObject, handles);
        end

        % Value changed function: MCC_Choice
        function MCC_Choice_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles.MCC = get(hObject,'Value');  % 1 = None, 2 = Cluster, 3 = TFCE, 4 = T max
            test        = isempty(handles.MCC);
            if test == 1
                handles.MCC = 1;
            end
            guidata(hObject, handles);
        end

        % Button pushed function: Partial_coef
        function Partial_coef_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            [FileName,PathName,FilterIndex]=uigetfile('LIMO.mat','Select a subject LIMO file');
            if FileName ~=0
                cd(PathName); handles.LIMO = load('LIMO.mat');
                limo_semi_partial_coef(handles.LIMO.LIMO)
            end
            guidata(hObject, handles);
        end

        % Button pushed function: Quit
        function Quit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            clc; uiresume
            guidata(hObject, handles);
            delete(handles.figure1)
            limo_gui_App
        end

        % Value changed function: add_bootstrap
        function add_bootstrap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            M = get(hObject,'Value');
            if M == 1
                handles.bootstrap = 1000;
                disp('bootstrap is on');
            elseif M == 0
                handles.bootstrap = 0;
                disp('boostrap is off');
            end
            
            guidata(hObject, handles);
        end

        % Value changed function: add_tfce
        function add_tfce_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            M = get(hObject,'Value');
            if M == 1
                handles.tfce = 1;
                disp('tfce is on');
            elseif M == 0
                handles.tfce = 0;
                disp('tfce is off');
            end
            guidata(hObject, handles);
        end

        % Value changed function: p_value
        function p_value_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles.p = str2double(get(hObject,'String'));
            test = isempty(handles.p);
            if test == 1
                handles.p = 0.05;
            end
            guidata(hObject, handles);
        end

        % Value changed function: Image_results
        function display_results(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            [FileName,PathName,FilterIndex]=limo_get_result_file;
            if FilterIndex ~= 0
                cd(PathName);
                if contains(FileName,'tfce')
                    if exist(fullfile(fileparts(PathName(1:end-1)),FileName(6:end)),'file')
                        [PathName,FileName] = fileparts(fullfile(fileparts(PathName(1:end-1)),FileName(6:end)));
                        FileName = [FileName '.mat'];
                    else
                        error('parent file %s not found',fullfile(fileparts(PathName(1:end-1)),FileName(6:end)))
                    end
                end
                tmp          = load([PathName filesep 'LIMO.mat']);
                handles.LIMO = tmp.LIMO; clear tmp
                handles      = check_boot_and_tfce(app,handles,fullfile(PathName,FileName));
                limo_display_results(1,FileName,PathName,handles.p,handles.MCC,handles.LIMO);
            end
            guidata(hObject, handles);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'limo_results';
            app.figure1.Tag = 'figure1';
            app.Visualize = uibuttongroup(app.figure1);
            app.Visualize.Title = 'Visualize';
            app.Visualize.Tag = 'Visualize';
            app.Topoplot = uibutton(app.Visualize, 'state');
            app.Topoplot.Tag = 'Topoplot';
            app.Topoplot.Tooltip = 'display all electrodes for a given time range';
            app.Topoplot.Text = 'Topographic plot';
            app.Image_results = uibutton(app.Visualize, 'state');
            app.Image_results.ValueChangedFcn = createCallbackFcn(app, @display_results, true);
            app.Image_results.Tag = 'Image_results';
            app.Image_results.Tooltip = 'display all electrodes / time points';
            app.Image_results.Text = 'Channel plot';
            app.ERP = uibutton(app.Visualize, 'state');
            app.ERP.Tag = 'ERP';
            app.ERP.Tooltip = 'Plot evoked and modeled ERP';
            app.ERP.Text = 'Waveform plot';
            app.Image = uiimage(app.Visualize);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'channel_plot.jpg');
            app.Image_2 = uiimage(app.Visualize);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'img', 'topoplot.jpg');
            app.Image_3 = uiimage(app.Visualize);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'img', 'waveform.jpg');
            app.New_Analyzes = uibuttongroup(app.figure1);
            app.New_Analyzes.Title = 'Post-hoc Analyzes';
            app.New_Analyzes.Tag = 'New_Analyzes';
            app.New_Contrast = uibutton(app.New_Analyzes, 'state');
            app.New_Contrast.Tag = 'New_Contrast';
            app.New_Contrast.Tooltip = 'call the contrast manager';
            app.New_Contrast.Text = 'New Contrast';
            app.Partial_coef = uibutton(app.New_Analyzes, 'push');
            app.Partial_coef.ButtonPushedFcn = createCallbackFcn(app, @Partial_coef_Callback, true);
            app.Partial_coef.Tag = 'Partial_coef';
            app.Partial_coef.Tooltip = 'compute and diplay semi-partial coefficients ie the amount of unique variance explained in the data';
            app.Partial_coef.Text = 'Semi-Partial coef';
            app.Help = uibutton(app.figure1, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Tag = 'Help';
            app.Help.Tooltip = 'Help';
            app.Help.Text = 'Help';
            app.Quit = uibutton(app.figure1, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @Quit_Callback, true);
            app.Quit.Tag = 'Quit';
            app.Quit.Tooltip = 'Quit';
            app.Quit.Text = 'Quit';
            app.Title = uilabel(app.figure1);
            app.Title.Tag = 'Title';
            app.Title.Text = 'LINEAR MODELING TOOLBOX: Results';
            app.Stat_panel = uipanel(app.figure1);
            app.Stat_panel.Title = 'Statistics';
            app.Stat_panel.Tag = 'Stat_panel';
            app.text_MCC = uilabel(app.Stat_panel);
            app.text_MCC.Tag = 'text_MCC';
            app.text_MCC.Text = 'Multiple Comparsons Corrections';
            app.text_pvalue = uilabel(app.Stat_panel);
            app.text_pvalue.Tag = 'text_pvalue';
            app.text_pvalue.Text = 'p-value threashold';
            app.p_value = uieditfield(app.Stat_panel, 'text');
            app.p_value.ValueChangedFcn = createCallbackFcn(app, @p_value_Callback, true);
            app.p_value.Tag = 'p_value';
            app.p_value.Value = '0.05';
            app.add_bootstrap = uicheckbox(app.Stat_panel);
            app.add_bootstrap.ValueChangedFcn = createCallbackFcn(app, @add_bootstrap_Callback, true);
            app.add_bootstrap.Tag = 'add_bootstrap';
            app.add_bootstrap.Tooltip = 'When doing image all - will compute bootstrap under H0 if not yet available';
            app.add_bootstrap.Text = 'Compute Bootstraps';
            app.add_tfce = uicheckbox(app.Stat_panel);
            app.add_tfce.ValueChangedFcn = createCallbackFcn(app, @add_tfce_Callback, true);
            app.add_tfce.Tag = 'add_tfce';
            app.add_tfce.Tooltip = 'When doing image all - will compute tfce if not yet available';
            app.add_tfce.Text = 'Compute TFCE';
            app.MCC_Choice = uidropdown(app.Stat_panel);
            app.MCC_Choice.Items = {'None', 'Clustering', 'TFCE', 'Max'};
            app.MCC_Choice.ValueChangedFcn = createCallbackFcn(app, @MCC_Choice_Callback, true);
            app.MCC_Choice.Tag = 'MCC_Choice';
            app.MCC_Choice.Tooltip = 'Type of multiple comparisons correction';
            app.MCC_Choice.Value = 'None';
            app.review_design = uibutton(app.Stat_panel, 'state');
            app.review_design.Tag = 'review_design';
            app.review_design.Tooltip = 'image the design matrix';
            app.review_design.Text = 'Review Design';
            app.Image_4 = uiimage(app.Stat_panel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'img', 'design.jpg');
            app.review_design_2 = uibutton(app.Stat_panel, 'state');
            app.review_design_2.Tag = 'review_design';
            app.review_design_2.Tooltip = 'image the design matrix';
            app.review_design_2.Text = 'Check Regressor Weights';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Copyright (C) LIMO Team';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = limo_results_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)limo_results_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end